classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UploadImageButton        matlab.ui.control.Button
        UploadVideoButton        matlab.ui.control.Button
        CalculateSpeedButton     matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        SpeedinmsEditFieldLabel  matlab.ui.control.Label
        SpeedinmsEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        image,  input_video_file  % Description
    end


    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
             [filename, pathname] = uigetfile('*.*', 'Pick a image file');
        if isequal(filename,0) || isequal(pathname,0)
           disp('User pressed cancel')
        else
            filename=strcat(pathname, filename);
            app.image=imread(filename);
            imshow(app.image,'parent', app.UIAxes);
        end
        end

        % Button pushed function: UploadVideoButton
        function UploadVideoButtonPushed(app, event)
            [file, path] = uigetfile('*.*', 'Select Video File');
            if isequal(file, 0)
                disp('User selected cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                %Displaying Video on Axes
                app.input_video_file = fullfile(path,file);
            end
        end

        % Button pushed function: CalculateSpeedButton
        function CalculateSpeedButtonPushed(app, event)
                v = VideoReader(app.input_video_file);
                d = vision.DeployableVideoPlayer;
                bg = rgb2gray(readFrame(v));
                last_frame = -1;
                frame = app.image;
                frame = rgb2gray(frame);
                thresh = graythresh(frame) * 256;
                ratio = 1.82/v.Width;
                frame_time = 1/v.FrameRate;
                sum = 0;
                count = 0;
                while hasFrame(v)
                    org = readFrame(v);
                    next = rgb2gray(org);
                    ans = abs(bg-next);
                    diff_size = size(ans);
                    
                    frame_start = 1;
                    for row = 1: diff_size(1)
                        for col = 1: diff_size(2)
                            if ans(row,col) > thresh
                                ans(row,col) = 255;
                                
                                if (last_frame ~= -1 && frame_start == 1)
                                    pixel_distance = abs(last_frame - col);
                                    actual_distance = pixel_distance * ratio;
                                    speed = actual_distance/frame_time;
                                    sum = sum + speed;
                                    count = count + 1;
                                    frame_start = 0;
                                end
                               last_frame = col;
                            else
                                ans(row,col) = 0;
                            end
                        end
                    end
                    d.step(org);
                    pause(1/v.FrameRate);
                end
                fr = (sum/count);
                app.SpeedinmsEditField.Value=fr;
        end

        % Value changed function: SpeedinmsEditField
        function SpeedinmsEditFieldValueChanged(app, event)
            value = app.SpeedinmsEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Obejct Image')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.UploadVideoButton = uibutton(app.UIFigure, 'push');
            app.UploadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideoButtonPushed, true);
            app.UploadVideoButton.Text = 'Upload  Video';
            app.CalculateSpeedButton = uibutton(app.UIFigure, 'push');
            app.CalculateSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSpeedButtonPushed, true);
            app.CalculateSpeedButton.Text = 'Calculate Speed';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SpeedinmsEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedinmsEditFieldLabel.Text = 'Speed  in m/s ';
            app.SpeedinmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedinmsEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedinmsEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end